classdef Interfaz_redes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CloseButton                  matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        SaveanalysisButton           matlab.ui.control.Button
        ASTDmatrixButton             matlab.ui.control.Button
        ViewfulltableButton          matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        AnalyzeButton                matlab.ui.control.Button
        SelectgenesEditField         matlab.ui.control.EditField
        SelectgenesEditFieldLabel    matlab.ui.control.Label
        AnalyzeAllButton             matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        ImportGenesDataButton        matlab.ui.control.Button
        CharacteristicstableButton   matlab.ui.control.Button
        SelectedModel                matlab.ui.control.EditField
        SelectedModelEditFieldLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        ClassifyButton               matlab.ui.control.Button
        ImportMLModelButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportMLModelButton
        function ImportMLModelButtonPushed(app, event)
           global model;
        [filename, path] = uigetfile('*.mat'); % agregar modelo knn entrenado
        if isequal(filename, 0)
            return; % user canceled operation

        else 
            app.SelectedModel.Enable = "on";
            setcsv = fullfile(path, string(filename));
            app.SelectedModel.Value = string(filename);
            model = importdata(setcsv);
        end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            global model Z A1 AttractorsTable
            celda = model.RequiredVariables;
            %A1 = readtable('TAM1384.csv');
            Z = [A1(:,2) A1(:,5)];
            yfit = model.predictFcn(Z); 
            yfit;
            A3 = [AttractorsTable table(yfit)];

            % Conteo de clases
            % Verificar si la tabla existe en el workspace
            if exist('A3', 'var') && istable(A3)
                % Verificar si la tabla tiene al menos 27 columnas
                if size(A3, 2) >= 27
                    % Contabilizar cuántos valores son 1
                    conteo1 = sum(A3{:, 27} == '1');
                    mensaje1 = 'There are ' + string(conteo1) + ' attractors of phenotype M1';

                    conteo2 = sum(A3{:, 27} == '2');
                    mensaje2 = 'There are ' + string(conteo2) + ' attractors of phenotype M2';

                    conteo3 = sum(A3{:, 27} == '3');
                    mensaje3 = 'There are ' + string(conteo3) + ' attractors of phenotype NLC';

                    conteo4 = sum(A3{:, 27} == '4');
                    mensaje4 = 'There are ' + string(conteo4) + ' attractors of phenotype M0';

                    mensajetotal = [mensaje1; mensaje2; mensaje3; mensaje4];
                    app.TextArea.Value = mensajetotal;
                    
                else
                    fprintf('Error: La tabla no tiene al menos 27 columnas.\n');
                end
            else
                fprintf('Error: La tabla "miTabla" no existe en el workspace.\n');
            end
        end

        % Button pushed function: CharacteristicstableButton
        function CharacteristicstableButtonPushed(app, event)
            global A1 AttractorsTable Genes
            %AQUÍ IRÍA LO DE EMILIANO
            [filename2, path2] = uigetfile('*.csv');
            app.ClassifyButton.Enable = "on";
            setcsv2 = fullfile(path2, string(filename2));
            A1 = readtable(setcsv2);
            De = table2array(A1(:,4)); %se extrae la columna De
            for i = 1:length(De)    % Se convierten los valores decimales a binarios
                Attr_1(i,:) = dec2bin(De(i),26);
            end
            AttractorsTable = array2table(Attr_1); %guardamos los genotipos en otra tabla
            AttractorsTable.Properties.VariableNames = Genes{1,1}; 
            
        end

        % Button pushed function: ImportGenesDataButton
        function ImportGenesDataButtonPushed(app, event)
            global Genes BOOL
            [filename3, path3] = uigetfile('*.txt');
            app.CharacteristicstableButton.Enable = "on";
            setcsv3 = fullfile(path3, string(filename3));
            Genes = textscan(fopen(setcsv3),'%s %s','Delimiter','=');

            N = "!"; %Se sustituye por MN
            A = "&"; %Se sustituye por MC
            O = "|"; %Se sustituye por MD
            
            % Definir que separa la función del gen ("=" o ",")
            L = "=";
            
            %Leemos el archivo de la red, cambiar el txt segun el caso
            NET = Genes;
            %NET = textscan(fopen('26Nodos2.txt'),'%s %s','Delimiter',L);
            
            % Separamos entre genes y funciones
            FUN = NET{2};
            % Creamos una tabla vacia para rellenarla
            BOOL = cell(length(FUN),3);
            %% REESCRITURA
            % Aquí extraemos la función de cada gen
            for i=1:length(FUN)
                % Enumeramos las funciones
                n = i;
                g = ['f_x' '(' num2str(n) ')'];
                % Extraemos las funciones booleanas
                f = num2str(cell2mat(FUN(i,1)));
                f = regexprep(f, '\s+', ' ');
                f = regexprep(f, '\(\s*(\w+\s*\|\s*\w+)\s*\|\s*', '(\($1\) | ');
                f = regexprep(f, '\s*\|\s*(\w+\s*\|\s*\w+)\s*\)', ' | \($1\))');
                k = 0;
                %% REESCRITURA SIMPLE ANDS
                AND = strfind(f,A);
                % Solo se sustituye el AND simple
               
                if ~isempty(AND)
                    f = replace(f,A,'*');
                    f = char(f);
                end
                f = regexprep(f, '\s+', ' ');
                %% CICLO PARA TODOS LOS NOT
                NOT = strfind(f,N);
                if ~isempty(NOT)
                    for k = 1:length(NOT)
                        MC = 0;
                        cont = 0;
                        NOT = strfind(f,N);
                        PA = strfind(f,"(");
                        PC = strfind(f,")");
                        Sig = f(NOT(:,1)+2);
                        % Si después del NOT hay un parentesis
                        if Sig == '('
                            PC = strfind(f,')');
                            PCS = PC - NOT(:,1)>0;
                            PPC = length(PC) - sum(PCS) + 1;
                            PCD = PC(PPC:end);
                            desp = f(NOT(:,1)+2:end);
                            for c = 1:length(desp)
                                if desp(c) == '('
                                    cont = cont + 1;
                                    % Maximos cerrados
                                    MC = MC+1;
                                elseif desp(c) == ')'
                                    cont = cont - 1;
                                end
                                if cont <= 0
                                    break
                                end
                            end
                            f = [f(1:NOT(:,1)-1) '(1 - '...
                                f(NOT(:,1)+2:PCD(MC)) ')' f(PCD(MC)+1:end)];
                        % Si después del NOT NO hay un parentesis
                        elseif Sig ~= '('
                            % Podemos ver el tamaño del nombre del gen con esto
                            end_gen = regexp(f(NOT(:,1)+2:end), '\s|&|\|', 'once');
                            if ~isempty(end_gen)
                                end_gen = NOT(:,1) + end_gen;
                            else
                                end_gen = length(f);
                            end
                            % Agregamos el (1- con un parentesis al final del gen
                            f = [f(1:NOT(:,1)-1) '(1 - ' f(NOT(:,1)+2:end_gen) ')'...
                                f(end_gen+1:end)];
                            
                        end
                        f = char(f);
                    end
                end
            
                OR = strfind(f,O);
                %% CICLO PARA TODOS LOS OR
                while k <= length(OR)
                    % Encontramos donde está cada operador lógico
                    k = 0;
                    MA = 0;
                    MC = 0;
                    cont = 0;
                    % Expresión regular para detectar las partes con '|' dentro de paréntesis
                    forma = '\(([^()]*\|[^()]*)\)';
                    PA = strfind(f,"(");
                    PC = strfind(f,")");
                    OR = strfind(f,O);
                    % Si no hay parentesis
                    if isempty(PA)
                        if ~isempty(OR)
                            % Sustituimos el OR 
                            f(OR(:,1)) = '+';
                            % Dividimos la parte de lado izquierdo y derecho del OR
                            primero = f(1:OR(:,1)-2);
                            segundo = f(OR(:,1)+2:end);
                            % Agregamos los parentesis necesarios para tener la forma: 
                            % A + (B) - A * (B)
                            f = insertAfter(f,OR(:,1) +1,'(');
                            f2 = ") - " + primero + " * (" + segundo + ")";
                            f = f + f2;
                        end
                    end
                    OR = strfind(f,O);
                    if ~isempty(OR)
                        PA = strfind(f,"(");
                        PC = strfind(f,")");
                        % Parentesis abiertos despues (Binario y Decimal)
                        PAB = PA - OR(:,1)>0;
                        PPA = length(PA) - sum(PAB) + 1;
                        PAD = PA(PPA:end);
                        % Parentesis cerrados despues (Binario y Decimal)
                        PCB = PC - OR(:,1)>0;
                        PPC = length(PC) - sum(PCB) + 1;
                        PCD = PC(PPC:end);
                        % Encuentra las coincidencias
                        simples = regexp(f, forma, 'match');
                        % Si hay funciones simples ej: (IL10|STAT1)
                        if ~isempty(simples)
                            for j = 1:length(simples)
                                exp = simples{j};
                                OR = strfind(exp,O);
                                primero = exp(2:OR(:,1)-2);
                                segundo = exp(OR(:,1)+2:end-1);
                                remp = ['(' primero ' + ' segundo ' - '...
                                    primero ' * ' segundo ')'];
                                f = regexprep(f, regexptranslate('escape', exp), remp);
                            end
                        % Si parentesis abiertos y cerrados despues es igual
                        elseif sum(PAB) == sum(PCB) && sum(PAB)~=0
                            primero = ['(' f(1:OR(:,1)-2) ')'];
                            segundo = ['(' f(OR(:,1)+2:end) ')'];
                            f = [primero ' + ' segundo ' - ' ...
                                primero ' * ' segundo];
                        % Si no hay parentesis abiertos despues
                        elseif sum(PAB) == 0 && ~isempty(PA)
                            
                            ant = f(1:OR(:,1)-2);
                            cont = 0;
                            % En este ciclo cont = 0 cuando se cierran 
                            % todos, MC es el numero de parentesis
                            for c = length(ant):-1:1
                                if ant(c) == '('
                                    cont = cont - 1;
                                elseif ant(c) == ')'
                                    cont = cont + 1;
                                    % Maximos cerrados
                                    MC = MC+1;
                                end
                                if cont <= 0
                                    break
                                end
                            end
                            
                            MC = MC-1;
                            primero = ['(' f(PA(end-MC):OR(:,1)-2) ')'];
                            if sum(PCB) == 0
                                segundo = ['(' f(OR(:,1)+2:end) ')'];
                                f = [f(1:PA(end-MC)-1) primero ' + ' segundo...
                                ' - ' primero ' * ' segundo];
                            elseif sum(PCB)~= 0
                                segundo = ['(' f(OR(:,1)+2:PC(PPC)-1) ')'];
                                f = [f(1:PA(end-MC)-1) primero ' + ' segundo...
                                ' - ' primero ' * ' segundo f(PC(PPC):end)];
                            end
                            
                        
                        % Si no hay parentesis cerrados despues
                        elseif sum(PCD) == 0 && ~isempty(PA)
                            primero = ['(' f(1:OR(:,1)-2) ')'];
                            segundo = ['(' f(OR(:,1)+2:end) ')'];
                            f = [primero ' + ' segundo...
                                ' - ' primero ' * ' segundo];
                        % Si hay más parentesis cerrados que abiertos después
                       
                        elseif sum(PAB) < sum(PCB) && sum(PAB)~=0
                            ant = f(1:OR(:,1)-2);
                            for c = length(ant):-1:1
                                if ant(c) == ')'
                                    cont = cont + 1;
                                    MA = MA + 1;
                                elseif ant(c) == '('
                                    cont = cont - 1;
                                    
                                end
                                if cont == 0
                                    break
                                end
                            end
                            MA = MA + 1;
                
                            primero = ['(' f(PA(PPA-MA)+1:OR(:,1)-2) ')'];
                            f1 = f(PA(PPA-MA):end);
                            for c = 1:length(f1)
                                if f1(c) == '('
                                    cont = cont + 1;
                                    MC = MC + 1;
                                elseif f1(c) == ')'
                                    cont = cont - 1;
                                    
                                end
                                if cont == 0
                                    break
                                end
                                
                            end
                            MC = MC - MA;
                            segundo = ['(' f(OR(:,1)+2:PCD(MC)) ')'];
                            f = [f(1:PA(PPA-MA)) primero ' + ' segundo ' - '...
                                primero ' * ' segundo f(PCD(MC)+1:end)];
                        end
                        OR = strfind(f,O);
            
                    elseif isempty(OR) 
                        break
                    end
            
                    f = regexprep(f, '\s+', ' ');
                    f = regexprep(f, '\((\w+)\)', '$1');
                end


                f = char(f);
                
                f = regexprep(f, '\s+', ' ');
                f = regexprep(f, '\((\w+)\)', '$1');  
                
                BOOL{i,1} = [g '=' f];
                f = ['(' f ')'];
                f = regexprep(f, '\((\w+)\)', '$1');  
                BOOL{i,2} = [' ' g '=' f ' + ' 'u' ...
                    ' - ' f ' * ' 'u'];
                BOOL{i,3} = [' ' g '=' f ' * ' 'u'];
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global estado
            app.Switch.ValueChangedFcn;
            estado = app.Switch.Value;
        end

        % Value changed function: SelectgenesEditField
        function SelectgenesEditFieldValueChanged(app, event)
            global ac
            ac=[];
            value = app.SelectgenesEditField.Value;
            value2=split(value,{' ',','});
            ac = str2double(value2)';
            %class(ac);     %para ver que tipo de variable es ac
        end

        % Button pushed function: AnalyzeAllButton
        function AnalyzeAllButtonPushed(app, event)
            global Data ac estado Attr KO BOOL Attr
            Attr = load("TAMCLL_attractors_class.mat");
            Attractors = Attr.X;
            De = table2array(Attractors(:,1:26));
            for i = 1:length(De)
                Attr_de(i,:) = bin2dec(num2str(De(i,:)));
            end
            
            i_c = Attractors{1:3,1:26};
            for m = 1:size(i_c,1)
                x_c(m)=sum(i_c(m,:));  % Sum of active/inactive nodes for the initial condition (Value between 0-26)
            end

            Ci = [];
            fileName = 'B2Diff_CLLGRN_Misa_interfaz.m';  % Nombre del archivo
            contadorgen = 0;
            espera=waitbar(0,'Analyzing...');
            Gen = [];
            KO = [];
            Fenotipo1_1 = [];
            Fenotipo2_2 = [];
            flag = true;
            %if OR (enciende genes '1','On') entonces todo esto
            %else AND (apaga genes '0', 'Off') todo esto igual cambiado para funciones and
            
            if estado == 1
            for i=1:length(BOOL)
            contadorgen = contadorgen+1;
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{i,1}, BOOL{i,2});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            %% For para lo anterior 
            for l=1:size(i_c,1) 
                [X_i,X_f,x_c1] = Bifurcation_CLL_OR_interfaz(i_c(l,:),x_c(l)); 
                Xi(l,:) = X_i(1,:); 
                Xf(l,:) = X_f(end,:); 
                Xc(:,l) = x_c1; 
                %eval([['x_c',num2str(l)],'=x_c']) 
                %clc 
            end 
            
            %% 
            contador = 0; 
            for n=1:size(i_c,1) 
                G1 = bin2dec(num2str(Xi(n,:))); 
                G2 = bin2dec(num2str(Xf(n,:))); 
                if G1 ~= G2 
                    B=[];
                    contador = contador + 1;
                    ci = Xi(n,:);
                    cf = Xf(n,:);
                    xc = Xc(:,n);
            
                    Ci(contador,:) = ci;
                    Cf(contador,:) = cf;
                    Ci_De(contador) = bin2dec(num2str(Ci(contador,:)));
                    class1(contador) = find(Attr_de == Ci_De(contador));
                    Fenotipo1(contador,:) = Attractors(class1(contador),27);
                    Cf_De(contador) = bin2dec(num2str(Cf(contador,:)));
                    class2(contador) = find(Attr_de == Cf_De(contador));
                    Fenotipo2(contador,:) = Attractors(class2(contador),27);
                    Gen(contador,:) = contadorgen;
            
                    X_c(:,contador) = round(xc);
                    B = find(X_c(:,contador)==X_c(end,contador));
                    tt(contador,:) = B(1)*0.01;

                    flag=true;
                    %disp('sí transiciona') 
                else 
                    %disp('no transiciona')
                    flag = false;
                end
            waitbar(i/length(BOOL),espera,'Analyzing...')
            if flag==1
                Fenotipo1_1 = [Fenotipo1_1; table2array(Fenotipo1)];
                Fenotipo2_2 = [Fenotipo2_2; table2array(Fenotipo2)];
                KO = [KO; Ci, Cf, tt, Gen];   
            end
            end
            
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{i,2}, BOOL{i,1});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            end
            close(espera)
            Data = table(Fenotipo1_1, KO(:,1:26), Fenotipo2_2, KO(:,27:52), KO(:,53), KO(:,54)); 

            Data.Properties.VariableNames{1} = 'Fenotipo1';
            Data.Properties.VariableNames{2} = 'Estado inicial';
            Data.Properties.VariableNames{3} = 'Fenotipo2';
            Data.Properties.VariableNames{4} = 'Estado final';
            Data.Properties.VariableNames{5} = 't de transición';
            Data.Properties.VariableNames{6} = 'Gen';
            Data = sortrows(Data,"Gen","ascend");
            
            PP = string(Data.Gen);
            for i2 = 1:length(PP)
                if PP(i2) == '1'
                    PP(i2) = 'IL1';
                elseif PP(i2) == '2'
                    PP(i2) = 'IL10';
                elseif PP(i2) == '3'
                    PP(i2) = 'HMGB1';
                elseif PP(i2) == '4'
                    PP(i2) = 'IFNg';
                elseif PP(i2) == '5'
                    PP(i2) = 'GMCSF';
                elseif PP(i2) == '6'
                    PP(i2) = 'LPS';
                elseif PP(i2) == '7'
                    PP(i2) = 'IC';
                elseif PP(i2) == '8'
                    PP(i2) = 'IL13';
                elseif PP(i2) == '9'
                    PP(i2) = 'IL4';
                elseif PP(i2) == '10'
                    PP(i2) = 'MCSF';
                elseif PP(i2) == '11'
                    PP(i2) = 'IFNgR';
                elseif PP(i2) == '12'
                    PP(i2) = 'STAT1';
                elseif PP(i2) == '13'
                    PP(i2) = 'STAT5';
                elseif PP(i2) == '14'
                    PP(i2) = 'NFkb';
                elseif PP(i2) == '15'
                    PP(i2) = 'PPARg';
                elseif PP(i2) == '16'
                    PP(i2) = 'STAT6';
                elseif PP(i2) == '17'
                    PP(i2) = 'JMJD3';
                elseif PP(i2) == '18'
                    PP(i2) = 'STAT3';
                elseif PP(i2) == '19'
                    PP(i2) = 'IRF4';
                elseif PP(i2) == '20'
                    PP(i2) = 'IRF5';
                elseif PP(i2) == '21'
                    PP(i2) = 'EGF';
                elseif PP(i2) == '22'
                    PP(i2) = 'IL12';
                elseif PP(i2) == '23'
                    PP(i2) = 'TNFa';
                elseif PP(i2) == '24'
                    PP(i2) = 'TGFb';
                elseif PP(i2) == '25'
                    PP(i2) = 'HIF1A';
                elseif PP(i2) == '26'
                    PP(i2) = 'RAGE';
                end
            end
            Data.Gen=PP;
            
            app.UITable.Data = Data;

            elseif estado == 0 
            for i=1:length(BOOL)
            contadorgen = contadorgen+1;
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{i,1}, BOOL{i,3});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            %% For para lo anterior 
            for l=1:size(i_c,1) 
                [X_i,X_f,x_c1] = Bifurcation_CLL_AND_interfaz(i_c(l,:),x_c(l)); 
                Xi(l,:) = X_i(1,:); 
                Xf(l,:) = X_f(end,:); 
                Xc(:,l) = x_c1; 
                %eval([['x_c',num2str(l)],'=x_c']) 
                %clc 
            end 
            
            %% 
            contador = 0; 
            for n=1:size(i_c,1) 
                G1 = bin2dec(num2str(Xi(n,:))); 
                G2 = bin2dec(num2str(Xf(n,:))); 
                if G1 ~= G2 
                    B=[];
                    contador = contador + 1;
                    ci = Xi(n,:);
                    cf = Xf(n,:);
                    xc = Xc(:,n);
            
                    Ci(contador,:) = ci;
                    Cf(contador,:) = cf;
                    Ci_De(contador) = bin2dec(num2str(Ci(contador,:)));
                    class1(contador) = find(Attr_de == Ci_De(contador));
                    Fenotipo1(contador,:) = Attractors(class1(contador),27);
                    Cf_De(contador) = bin2dec(num2str(Cf(contador,:)));
                    class2(contador) = find(Attr_de == Cf_De(contador));
                    Fenotipo2(contador,:) = Attractors(class2(contador),27);
                    Gen(contador,:) = contadorgen;
            
                    X_c(:,contador) = round(xc);
                    B = find(X_c(:,contador)==X_c(end,contador));
                    tt(contador,:) = B(1)*0.01;

                    flag=true;
                    %disp('sí transiciona') 
                else 
                    %disp('no transiciona')
                    flag = false;
                end
            waitbar(i/length(BOOL),espera,'Analyzing...')
            if flag==1
                Fenotipo1_1 = [Fenotipo1_1; table2array(Fenotipo1)];
                Fenotipo2_2 = [Fenotipo2_2; table2array(Fenotipo2)];
                KO = [KO; Ci, Cf, tt, Gen];   
            end
            end
            
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{i,3}, BOOL{i,1});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            end
            
            close(espera)
            Data = table(Fenotipo1_1, KO(:,1:26), Fenotipo2_2, KO(:,27:52), KO(:,53), KO(:,54)); 

            Data.Properties.VariableNames{1} = 'Fenotipo1';
            Data.Properties.VariableNames{2} = 'Estado inicial';
            Data.Properties.VariableNames{3} = 'Fenotipo2';
            Data.Properties.VariableNames{4} = 'Estado final';
            Data.Properties.VariableNames{5} = 't de transición';
            Data.Properties.VariableNames{6} = 'Gen';
            Data = sortrows(Data,"Gen","ascend");
            
            PP = string(Data.Gen);
            for i2 = 1:length(PP)
                if PP(i2) == '1'
                    PP(i2) = 'IL1';
                elseif PP(i2) == '2'
                    PP(i2) = 'IL10';
                elseif PP(i2) == '3'
                    PP(i2) = 'HMGB1';
                elseif PP(i2) == '4'
                    PP(i2) = 'IFNg';
                elseif PP(i2) == '5'
                    PP(i2) = 'GMCSF';
                elseif PP(i2) == '6'
                    PP(i2) = 'LPS';
                elseif PP(i2) == '7'
                    PP(i2) = 'IC';
                elseif PP(i2) == '8'
                    PP(i2) = 'IL13';
                elseif PP(i2) == '9'
                    PP(i2) = 'IL4';
                elseif PP(i2) == '10'
                    PP(i2) = 'MCSF';
                elseif PP(i2) == '11'
                    PP(i2) = 'IFNgR';
                    elseif PP(i2) == '12'
                    PP(i2) = 'STAT1';
                elseif PP(i2) == '13'
                    PP(i2) = 'STAT5';
                elseif PP(i2) == '14'
                    PP(i2) = 'NFkb';
                elseif PP(i2) == '15'
                    PP(i2) = 'PPARg';
                elseif PP(i2) == '16'
                    PP(i2) = 'STAT6';
                elseif PP(i2) == '17'
                    PP(i2) = 'JMJD3';
                elseif PP(i2) == '18'
                    PP(i2) = 'STAT3';
                elseif PP(i2) == '19'
                    PP(i2) = 'IRF4';
                elseif PP(i2) == '20'
                    PP(i2) = 'IRF5';
                elseif PP(i2) == '21'
                    PP(i2) = 'EGF';
                    elseif PP(i2) == '22'
                    PP(i2) = 'IL12';
                elseif PP(i2) == '23'
                    PP(i2) = 'TNFa';
                elseif PP(i2) == '24'
                    PP(i2) = 'TGFb';
                elseif PP(i2) == '25'
                    PP(i2) = 'HIF1A';
                elseif PP(i2) == '26'
                    PP(i2) = 'RAGE';
                end
            end
            Data.Gen=PP;

            app.UITable.Data = Data;
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            global Data ac estado Attr KO BOOL Attr
            Attr = load("TAMCLL_attractors_class.mat");
            Attractors = Attr.X;
            De = table2array(Attractors(:,1:26));
            for i = 1:length(De)
                Attr_de(i,:) = bin2dec(num2str(De(i,:)));
            end
            
            i_c = Attractors{1:3,1:26}; %CONDICIONES INICIALES
            for m = 1:size(i_c,1)
                x_c(m)=sum(i_c(m,:));  % Sum of active/inactive nodes for the initial condition (Value between 0-26)
            end

            Ci = [];
            fileName = 'B2Diff_CLLGRN_Misa_interfaz.m';  % Nombre del archivo
            contadorgen = 0;
            Gen = [];
            KO = [];
            Fenotipo1_1 = [];
            Fenotipo2_2 = [];
            espera=waitbar(0,'Analyzing...');
            flag = true;
            %if OR (enciende genes 'On') entonces todo esto
            %else AND (apaga genes 'Off') todo esto igual cambiado para funciones and
            
            if estado == 1
            for i=1:length(ac)
            contadorgen = contadorgen+1;
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{ac(i),1}, BOOL{ac(i),2});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            %% For para lo anterior 
            for l=1:size(i_c,1) 
                [X_i,X_f,x_c1] = Bifurcation_CLL_OR_interfaz(i_c(l,:),x_c(l)); 
                Xi(l,:) = X_i(1,:); 
                Xf(l,:) = X_f(end,:); 
                Xc(:,l) = x_c1; 
                %eval([['x_c',num2str(l)],'=x_c']) 
                %clc 
            end 
            
            %% 
            contador = 0; 
            for n=1:size(i_c,1) 
                G1 = bin2dec(num2str(Xi(n,:))); 
                G2 = bin2dec(num2str(Xf(n,:))); 
                if G1 ~= G2 
                    B=[];
                    contador = contador + 1;
                    ci = Xi(n,:);
                    cf = Xf(n,:);
                    xc = Xc(:,n);
            
                    Ci(contador,:) = ci;
                    Cf(contador,:) = cf;
                    Ci_De(contador) = bin2dec(num2str(Ci(contador,:)));
                    class1(contador) = find(Attr_de == Ci_De(contador));
                    Fenotipo1(contador,:) = Attractors(class1(contador),27);
                    Cf_De(contador) = bin2dec(num2str(Cf(contador,:)));
                    class2(contador) = find(Attr_de == Cf_De(contador));
                    Fenotipo2(contador,:) = Attractors(class2(contador),27);
                    Gen(contador,:) = contadorgen;
            
                    X_c(:,contador) = round(xc);
                    B = find(X_c(:,contador)==X_c(end,contador));
                    tt(contador,:) = B(1)*0.01;

                    flag=true;
                    %disp('sí transiciona') 
                else 
                    %disp('no transiciona')
                    flag = false;
                end
            waitbar(i/length(ac),espera,'Analyzing...')
            if flag==1
                Fenotipo1_1 = [Fenotipo1_1; table2array(Fenotipo1)];
                Fenotipo2_2 = [Fenotipo2_2; table2array(Fenotipo2)];
                KO = [KO; Ci, Cf, tt, Gen];   
            end
            end
            
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{ac(i),2}, BOOL{ac(i),1});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            end

            close(espera)

            Data = table(Fenotipo1_1, KO(:,1:26), Fenotipo2_2, KO(:,27:52), KO(:,53), KO(:,54)); 

            Data.Properties.VariableNames{1} = 'Fenotipo1';
            Data.Properties.VariableNames{2} = 'Estado inicial';
            Data.Properties.VariableNames{3} = 'Fenotipo2';
            Data.Properties.VariableNames{4} = 'Estado final';
            Data.Properties.VariableNames{5} = 't de transición';
            Data.Properties.VariableNames{6} = 'Gen';
            Data = sortrows(Data,"Gen","ascend");
            
            PP = string(Data.Gen);
            for i2 = 1:length(PP)
                if PP(i2) == '1'
                    PP(i2) = 'IL1';
                elseif PP(i2) == '2'
                    PP(i2) = 'IL10';
                elseif PP(i2) == '3'
                    PP(i2) = 'HMGB1';
                elseif PP(i2) == '4'
                    PP(i2) = 'IFNg';
                elseif PP(i2) == '5'
                    PP(i2) = 'GMCSF';
                elseif PP(i2) == '6'
                    PP(i2) = 'LPS';
                elseif PP(i2) == '7'
                    PP(i2) = 'IC';
                elseif PP(i2) == '8'
                    PP(i2) = 'IL13';
                elseif PP(i2) == '9'
                    PP(i2) = 'IL4';
                elseif PP(i2) == '10'
                    PP(i2) = 'MCSF';
                elseif PP(i2) == '11'
                    PP(i2) = 'IFNgR';
                    elseif PP(i2) == '12'
                    PP(i2) = 'STAT1';
                elseif PP(i2) == '13'
                    PP(i2) = 'STAT5';
                elseif PP(i2) == '14'
                    PP(i2) = 'NFkb';
                elseif PP(i2) == '15'
                    PP(i2) = 'PPARg';
                elseif PP(i2) == '16'
                    PP(i2) = 'STAT6';
                elseif PP(i2) == '17'
                    PP(i2) = 'JMJD3';
                elseif PP(i2) == '18'
                    PP(i2) = 'STAT3';
                elseif PP(i2) == '19'
                    PP(i2) = 'IRF4';
                elseif PP(i2) == '20'
                    PP(i2) = 'IRF5';
                elseif PP(i2) == '21'
                    PP(i2) = 'EGF';
                    elseif PP(i2) == '22'
                    PP(i2) = 'IL12';
                elseif PP(i2) == '23'
                    PP(i2) = 'TNFa';
                elseif PP(i2) == '24'
                    PP(i2) = 'TGFb';
                elseif PP(i2) == '25'
                    PP(i2) = 'HIF1A';
                elseif PP(i2) == '26'
                    PP(i2) = 'RAGE';
                end
            end
            Data.Gen=PP;

            app.UITable.Data = Data;

            elseif estado==0 
                for i=1:length(ac)
            contadorgen = contadorgen+1;
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{ac(i),1}, BOOL{ac(i),3});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            %% For para lo anterior 
            for l=1:size(i_c,1) 
                [X_i,X_f,x_c1] = Bifurcation_CLL_AND_interfaz(i_c(l,:),x_c(l)); 
                Xi(l,:) = X_i(1,:); 
                Xf(l,:) = X_f(end,:); 
                Xc(:,l) = x_c1; 
                %eval([['x_c',num2str(l)],'=x_c']) 
                %clc 
            end 
            
            %% 
            contador = 0; 
            for n=1:size(i_c,1) 
                G1 = bin2dec(num2str(Xi(n,:))); 
                G2 = bin2dec(num2str(Xf(n,:))); 
                if G1 ~= G2 
                    B=[];
                    contador = contador + 1;
                    ci = Xi(n,:);
                    cf = Xf(n,:);
                    xc = Xc(:,n);
            
                    Ci(contador,:) = ci;
                    Cf(contador,:) = cf;
                    Ci_De(contador) = bin2dec(num2str(Ci(contador,:)));
                    class1(contador) = find(Attr_de == Ci_De(contador));
                    Fenotipo1(contador,:) = Attractors(class1(contador),27);
                    Cf_De(contador) = bin2dec(num2str(Cf(contador,:)));
                    class2(contador) = find(Attr_de == Cf_De(contador));
                    Fenotipo2(contador,:) = Attractors(class2(contador),27);
                    Gen(contador,:) = contadorgen;
            
                    X_c(:,contador) = round(xc);
                    B = find(X_c(:,contador)==X_c(end,contador));
                    tt(contador,:) = B(1)*0.01;

                    flag=true;
                    %disp('sí transiciona') 
                else 
                    %disp('no transiciona')
                    flag = false;
                end
                waitbar(i/length(ac),espera,'Analyzing...')
            if flag==1
                Fenotipo1_1 = [Fenotipo1_1; table2array(Fenotipo1)];
                Fenotipo2_2 = [Fenotipo2_2; table2array(Fenotipo2)];
                KO = [KO; Ci, Cf, tt, Gen];   
            end
            end
            
            % Leer el contenido del archivo
            fid = fopen(fileName, 'rt');  % Abrir el archivo en modo lectura
            fileContent = fread(fid, '*char')';  % Leer el contenido como texto
            fclose(fid);  % Cerrar el archivo
            
            % Comentar la función
            commentedContent = strrep(fileContent, BOOL{ac(i),3}, BOOL{ac(i),1});  % Comentar la palabra "function"
            
            % Guardar el contenido comentado de nuevo en el archivo
            fid = fopen(fileName, 'wt');  % Abrir el archivo en modo escritura
            fwrite(fid, commentedContent);  % Escribir el contenido comentado
            fclose(fid);  % Cerrar el archivo
            end
            close(espera)
            Data = table(Fenotipo1_1, KO(:,1:26), Fenotipo2_2, KO(:,27:52), KO(:,53), KO(:,54)); 

            Data.Properties.VariableNames{1} = 'Fenotipo1';
            Data.Properties.VariableNames{2} = 'Estado inicial';
            Data.Properties.VariableNames{3} = 'Fenotipo2';
            Data.Properties.VariableNames{4} = 'Estado final';
            Data.Properties.VariableNames{5} = 't de transición';
            Data.Properties.VariableNames{6} = 'Gen';
            Data = sortrows(Data,"Gen","ascend");
            
            PP = string(Data.Gen);
            for i2 = 1:length(PP)
                if PP(i2) == '1'
                    PP(i2) = 'IL1';
                elseif PP(i2) == '2'
                    PP(i2) = 'IL10';
                elseif PP(i2) == '3'
                    PP(i2) = 'HMGB1';
                elseif PP(i2) == '4'
                    PP(i2) = 'IFNg';
                elseif PP(i2) == '5'
                    PP(i2) = 'GMCSF';
                elseif PP(i2) == '6'
                    PP(i2) = 'LPS';
                elseif PP(i2) == '7'
                    PP(i2) = 'IC';
                elseif PP(i2) == '8'
                    PP(i2) = 'IL13';
                elseif PP(i2) == '9'
                    PP(i2) = 'IL4';
                elseif PP(i2) == '10'
                    PP(i2) = 'MCSF';
                elseif PP(i2) == '11'
                    PP(i2) = 'IFNgR';
                    elseif PP(i2) == '12'
                    PP(i2) = 'STAT1';
                elseif PP(i2) == '13'
                    PP(i2) = 'STAT5';
                elseif PP(i2) == '14'
                    PP(i2) = 'NFkb';
                elseif PP(i2) == '15'
                    PP(i2) = 'PPARg';
                elseif PP(i2) == '16'
                    PP(i2) = 'STAT6';
                elseif PP(i2) == '17'
                    PP(i2) = 'JMJD3';
                elseif PP(i2) == '18'
                    PP(i2) = 'STAT3';
                elseif PP(i2) == '19'
                    PP(i2) = 'IRF4';
                elseif PP(i2) == '20'
                    PP(i2) = 'IRF5';
                elseif PP(i2) == '21'
                    PP(i2) = 'EGF';
                    elseif PP(i2) == '22'
                    PP(i2) = 'IL12';
                elseif PP(i2) == '23'
                    PP(i2) = 'TNFa';
                elseif PP(i2) == '24'
                    PP(i2) = 'TGFb';
                elseif PP(i2) == '25'
                    PP(i2) = 'HIF1A';
                elseif PP(i2) == '26'
                    PP(i2) = 'RAGE';
                end
            end
            Data.Gen=PP;
            
            app.UITable.Data = Data;
            end
        end

        % Button pushed function: ViewfulltableButton
        function ViewfulltableButtonPushed(app, event)
            global Data 
            writetable(Data,'analisis_bifurcacion.csv')
        end

        % Button pushed function: ASTDmatrixButton
        function ASTDmatrixButtonPushed(app, event)
    global Attr Genes KO Data
    Attractors = Attr.X;
    Attr2 = Data;
    KO1 = sortrows(KO,54);
    Genes2 = Genes{1};
    for i = 1:length(Genes2)
        % Quitar el espacio final si existe
        if Genes2{i}(end) == ' '
            Genes2{i} = Genes2{i}(1:end-1);
        end
    end
    
    % Inicializar variables
    numGenes = length(Genes2);
    resultadosTexto = ""; % Preasignar cadena vacía para resultados
    EST = [];
    GT = [];

    % Matriz de atractores en decimal
    De = table2array(Attractors(:, 1:26));
    for i1 = 1:length(De)
        Attr_de(i1,:) = bin2dec(num2str(De(i1, :)));
    end

    % Ciclo principal
    for i = 1:numGenes
        Genes3 = Attr2{:,6};  % Acceder correctamente a la columna
        Genes3 = cellstr(Genes3);
        z = find(strcmp(Genes3, Genes2{i}));
        if ~isempty(z)
        z1 = length(z);
        % Acceder correctamente a las filas de la tabla con índices z
        xi = KO1(z(1):z(end),1:26);
        xf = KO1(z(1):z(end),27:52);

        % Calcular tiempos de manipulación promedio
        Phenotypes = Attr2{z(1):z(end),1};
        tprom_M2 = calcularTiempoPromedio(Phenotypes, 'M2', KO1);
        tprom_M1 = calcularTiempoPromedio(Phenotypes, 'M1', KO1);
        tprom_NLC = calcularTiempoPromedio(Phenotypes, 'NLC', KO1);
        tprom_M0 = calcularTiempoPromedio(Phenotypes, 'M0', KO1);

        % Convertir xi y xf a decimal
        for i2 = 1:length(xi(1))
            xi_de(i2,:) = bin2dec(num2str(xi(i2,:)));
            xf_de(i2,:) = bin2dec(num2str(xf(i2,:)));
        end

        % Generar matriz de transiciones de 1384x1384
        Transitions_m = zeros(1384, 1384);

        % Construcción de la matriz de transiciones ASTD corregida
        for i3 = 1:length(xi_de)
            x_0 = Attr_de==xi_de(i3);
            x_d = Attr_de==xf_de(i3);
            Transitions_m(x_0,x_d) = 1;
        end

        % Guardar resultados para interfaz
        resultadosTexto = resultadosTexto + sprintf(['Activating the gene %s triggers %d transitions \n', ...
            'The average manipulation time for M2 is %g\n', ...
            'The average manipulation time for M1 is %g\n', ...
            'The average manipulation time for NLC is %g\n', ...
            'The average manipulation time for M0 is %g\n', ...
            '------------------------------------------------\n'], ...
            Genes2{i}, z1, tprom_M2, tprom_M1, tprom_NLC, tprom_M0);

        Cprop = PropChart([320 78 60 926],Transitions_m);
        xvalues = {'M2','M1','NLC','M0'};
        yvalues = {'M2','M1','NLC','M0'};
        figure
        heatmap(xvalues,yvalues,Cprop,'Xlabel','Initial State x_0','Ylabel','Final State x_d','Title',['Transitions of TAMCLL-GRN via ', Genes2{i}],'ColorLimits',[0 100])
        
        est = sum(Cprop(1,2:4)) + sum(Cprop(2, [1,3,4])) + sum(Cprop(3, [1,2,4])) + sum(Cprop(4, [1,2,3]));
        est2 = (est / 100) * z1;
        EST = [EST; round(est2)];
        GT = [GT; string(Genes2{i})];
        else
            continue
        end
    end

        normalized_EST = EST / max(EST);
        Transitions = sort(normalized_EST);

        figure
        plot(Transitions, '.', 'MarkerSize', 10, 'Color', 'k')
        xlabel('Genes')
        xlim([0 length(Transitions)])
        title('Tendency of genes to produce qualitative changes')

        for m = 1:length(GT)
        resultadosTexto = resultadosTexto + sprintf('The gene %s has a tendency of %d\n', GT{m}, normalized_EST(m));
        end
        resultadosTexto = resultadosTexto + sprintf('The gene with the highest tendency to generate transitions is the %s\n', Genes2{find(normalized_EST == 1)});
    
        % Mostrar resultados en el área de texto
        app.TextArea_2.Value = resultadosTexto; % Asignar el texto a TextArea

% Función auxiliar
function tprom = calcularTiempoPromedio(Phenotypes, tipo, KO1)
    Phenotypes = cellstr(Phenotypes);
    zTipo = find(strcmp(Phenotypes, tipo));
    if isempty(zTipo)
        tprom = 0;
    else
        tprom = mean(KO1(zTipo, 53));
    end
end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close all force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportMLModelButton = uibutton(app.UIFigure, 'push');
            app.ImportMLModelButton.ButtonPushedFcn = createCallbackFcn(app, @ImportMLModelButtonPushed, true);
            app.ImportMLModelButton.Text = 'Import ML Model';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.TextArea = uitextarea(app.UIFigure);
            app.SelectedModelEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedModelEditFieldLabel.Text = 'Selected Model';
            app.SelectedModel = uieditfield(app.UIFigure, 'text');
            app.CharacteristicstableButton = uibutton(app.UIFigure, 'push');
            app.CharacteristicstableButton.ButtonPushedFcn = createCallbackFcn(app, @CharacteristicstableButtonPushed, true);
            app.CharacteristicstableButton.Text = 'Characteristics table';
            app.ImportGenesDataButton = uibutton(app.UIFigure, 'push');
            app.ImportGenesDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportGenesDataButtonPushed, true);
            app.ImportGenesDataButton.Text = 'Import Genes Data';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [0 1];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 0;
            app.AnalyzeAllButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeAllButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeAllButtonPushed, true);
            app.AnalyzeAllButton.Text = 'Analyze All';
            app.SelectgenesEditFieldLabel = uilabel(app.UIFigure);
            app.SelectgenesEditFieldLabel.Text = 'Select genes';
            app.SelectgenesEditField = uieditfield(app.UIFigure, 'text');
            app.SelectgenesEditField.ValueChangedFcn = createCallbackFcn(app, @SelectgenesEditFieldValueChanged, true);
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Phenotype 1'; 'Initial State'; 'Phenotype 2'; 'Final state'; 'Manipulation time'; 'Gene'};
            app.UITable.RowName = {};
            app.ViewfulltableButton = uibutton(app.UIFigure, 'push');
            app.ViewfulltableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewfulltableButtonPushed, true);
            app.ViewfulltableButton.Text = 'View full table';
            app.ASTDmatrixButton = uibutton(app.UIFigure, 'push');
            app.ASTDmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ASTDmatrixButtonPushed, true);
            app.ASTDmatrixButton.Text = 'ASTD matrix';
            app.SaveanalysisButton = uibutton(app.UIFigure, 'push');
            app.SaveanalysisButton.Text = 'Save analysis';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_redes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end